classdef Swabbing_test_application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        FigurenameEditField        matlab.ui.control.EditField
        FigurenameEditFieldLabel   matlab.ui.control.Label
        SetpositionButton          matlab.ui.control.Button
        SwabButton                 matlab.ui.control.Button
        PicturenameEditField       matlab.ui.control.EditField
        PicturenameEditFieldLabel  matlab.ui.control.Label
        ForcedisplayLabel          matlab.ui.control.Label
        ArmpositionLabel           matlab.ui.control.Label
        ColourSelectDropDown       matlab.ui.control.DropDown
        ColourSelectDropDownLabel  matlab.ui.control.Label
        ArmYSlider                 matlab.ui.control.Slider
        YLabel                     matlab.ui.control.Label
        ArmXSlider                 matlab.ui.control.Slider
        XInputLabel                matlab.ui.control.Label
        DebugLabeltitle            matlab.ui.control.Label
        CameraModelLabel           matlab.ui.control.Label
        SavepictureButton          matlab.ui.control.Button
        TakepictureButton          matlab.ui.control.Button
        DebugLabel                 matlab.ui.control.Label
        saveForceFigButton         matlab.ui.control.Button
        CreateNewTestButton        matlab.ui.control.Button
        TestIDLabel                matlab.ui.control.Label
        ArmPositionAxes            matlab.ui.control.UIAxes
        ImgAx                      matlab.ui.control.UIAxes
        ColorAX                    matlab.ui.control.UIAxes
        ForceSensorAxes            matlab.ui.control.UIAxes
    end


    properties (Access = public)
        
     RsPipeLine realsense.pipeline % link to the realsense camera 
     ImgCellArray % Cell array holding the last take image by camera {full colour, red, green ,blue} each is technically stored as a full colour image but with some of the channels set to 0
    end
    
    properties (Access = private)
        currentTestDir % the path to the current tests directory (folder)
        currentTestName % Name of the directory in which the current tests data is being stored.
        dirExist = 0  % boolean value indicating if there exists a current test directory. d
        ImgCaptured = 0;
        forceSenseTimer

        forceSenseDevice % Serial device 
        nb_layer = 1; %for force sensor (IDK man it was there so it's here)
        dt=[0 0];%array conataining 2 timestamps: [when request received; when request sent] 
        vec = zeros(256, (1));%array containing the force values
        cmd = 16; % part of ported code for force sensor, since arduino used is a black box I have no idea why this is the way it is 
        prevArray = zeros(16);

        zaberCon
        zaberBeltDriveDevice % object holding the belt drive motor control
        zaberLeadScrewDevice % object holding the lead screw motor control
    end
    
    methods (Access = private)
        
        
        function [Img] = captureAndSplitColourImg(app)

            RsFrameSet = app.RsPipeLine.wait_for_frames(); % waits to receive frames 
            cf = RsFrameSet.get_color_frame(); % the pipeline reading portion of this is from intel Realsense example code 
            dd = cf.get_data();
            d = permute(reshape(dd',[3,cf.get_width(),cf.get_height()]),[3 2 1]);

            Img = cell(1, size(d,1)); %create cell array to hold images

            Img{1} = d; %save full colour image 

            rImgChannel = d; % save red image 
            rImgChannel(:,:,2) = 0;
            rImgChannel(:,:,3) = 0; % simply sets all other colour channels to zero
            Img{2} = rImgChannel;

            gImgChannel = d; % save green image 
            gImgChannel(:,:,1) = 0;
            gImgChannel(:,:,3) = 0;
            Img{3} = gImgChannel;

            bImgChannel = d; % save blue image 
            bImgChannel(:,:,1) = 0;
            bImgChannel(:,:,2) = 0;
            Img{4} = bImgChannel;

            app.ImgCaptured = 1; % updates flag so that rest of program knows image has been captured
        
            drawnow;
          
        end

        function [success,testDir, testName] = CreateNewTest(app)
            % creates new test Directory with the current data and time in
            % a file friendly format. It also updates the dir exist flag to
            % notify the rest of the program that a test has been made.
            dateTimeStr = string( datetime("now",'format','dd-MM-yy_HH.mm.ss')); % creates window file name appropriate date format 
            dataFilename = append("TestAt ",dateTimeStr);
            dirname = append(string(directoryGet()),"/data/");
            [success ,~,~]=mkdir(append(dirname,dataFilename));
            testDir = append(dirname,dataFilename);
            testName = dataFilename;
            app.currentTestDir = testDir;
            app.currentTestName = testName; % use this as the name of the test file.
            app.dirExist = 1;
            
            app.TestIDLabel.Text = testName;
        end
        
        function forceSensorReaderPlotter(app,~,~) %


            n = 16; % size on the n*n array to measure
            
            write(app.forceSenseDevice,app.cmd,"uint8");
      
            app.dt = read(app.forceSenseDevice,2,"uint32");
            xlabel(app.ForceSensorAxes,string(app.dt));
      
            if(app.nb_layer==2)
                app.vec = read(app.forceSenseDevice,2*n*n,"uint16");%get forces array
                array = reshape(app.vec,2*n,n); % reshape data
            else 
                app.vec = read(app.forceSenseDevice,n*n,"uint16");
                array = (reshape(app.vec,n,n))';
            end 
            contourf(app.ForceSensorAxes,array,[50,100,150,200,250,300,350,400,450,500]); % anything under a 50 is ignored as that seems to be the background reading
            colorbar(app.ForceSensorAxes); % add colour bar so the contour actually gives info


            app.prevArray = array;
        end
        
        function SaveForceFigure(app)
           
            if(app.dirExist == 1)
                exportgraphics(app.ForceSensorAxes, append(app.currentTestDir,"/ForceFig.png"));
            else 

                app.DebugLabel.Text = "Could not save figure";
            end
            
        end
    end
    
    methods (Access = public)
        
        function success  = SaveCurrentImg(app,Colour)
            % this function saves a specific or all colour channels of the
            % last taken image in the last created test directory.
           if app.dirExist == 1 && app.ImgCaptured % makes sure there is both image and directory to save
                if Colour == "all"
                    imwrite(app.ImgCellArray{1},append(app.currentTestDir,'/FullColourImage.png'));
                    success = 1;
                elseif Colour == "r"
                    imwrite(app.ImgCellArray{2},append(app.currentTestDir,'/RedImage.png'))
                    success = 1;
                elseif Colour == "g"
                    imwrite(app.ImgCellArray{3},append(app.currentTestDir,'/GreenImage.png'))
                    success = 1;
                elseif Colour == "b"
                    imwrite(app.ImgCellArray{3},append(app.currentTestDir,'/BlueImage.png'));
                    success = 1;
                else 
                    success = 0 ; 
                end
           else
               success = 0;
           end 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            import zaber.motion.binary.*
            import zaber.motion.*
            
             try 
                 app.zaberCon = Connection.openSerialPort('COM5'); % serial connection to zaber devices 
                 devices = app.zaberCon.detectDevices();
                 app.zaberBeltDriveDevice = devices(1);
                 app.zaberLeadScrewDevice = devices(2);
             
             
                 fprintf('Device %d has device ID %d.\n', app.zaberBeltDriveDevice.getDeviceAddress(), app.zaberBeltDriveDevice.getIdentity().getDeviceId());
             
                 % home devices
                 app.zaberBeltDriveDevice.home(Units.LENGTH_CENTIMETRES);
                 app.zaberLeadScrewDevice.home(Units.LENGTH_CENTIMETRES);
             
                 app.zaberCon.close();
             catch exception
                app.zaberCon.close();
                error(getReport(exception));
                             
             end

            app.forceSenseDevice = serialport("COM11",500000); % connect to the arduino ( select the right path: COM1, COM2 ... on windows)
            pause(1)%wait a bit

            if(app.nb_layer==2)
                 app.cmd=app.cmd+0x80;
            end

            RsCtx = realsense.context();
            Devs = RsCtx.query_devices();
            if ( numel(Devs) < 1 )
                error ( 'Not found RealSense Device' );
            end

            RsDev = Devs{1};
            cn = RsDev.get_info(realsense.camera_info.name);
            delete(RsDev);
            delete(RsCtx);
            app.CameraModelLabel.Text = sprintf('rs_capture_class < Camera : %s >',cn);
            app.RsPipeLine = realsense.pipeline();
            cnfg = realsense.config();
            cnfg.enable_stream(realsense.stream.color, 1280, 720, realsense.format.any_format, 6) % confugeration setting frame rate to 6/s so that camera doesn't overheat 
            app.RsPipeLine.start(cnfg); % begin capturing images 

            app.TakepictureButton.Enable = "off"; % wait for the first 30 frames so that autofocus has time to addjust
            for i = 1:30
                app.RsPipeLine.wait_for_frames();
            end 
            app.TakepictureButton.Enable = "on";

            app.CreateNewTest() % creates new test as soon as app is loaded to avoid save file path not being valid
            captureAndSplitColourImg(app);
            app.ImgCellArray = captureAndSplitColourImg(app); % capture and show intial image 
            imshow(app.ImgCellArray{1},'Parent',app.ImgAx);
            montage({app.ImgCellArray{2},app.ImgCellArray{3},app.ImgCellArray{4}}, 'Parent',app.ColorAX,'Size',[1 3]);

             app. forceSenseTimer= timer("ExecutionMode","fixedRate","Period",0.1,"BusyMode","queue","TimerFcn",@(timer_obj, event)app.forceSensorReaderPlotter); % set up timer for force sensor 
            if strcmp(app.forceSenseTimer.Running,"off")
               start(app.forceSenseTimer); %if the timer isn't running, run it 
            end
            

        end

        % Button pushed function: TakepictureButton
        function TakepictureButtonPushed(app, event)
            app.TakepictureButton.Enable = "off";
            app.ImgCellArray = captureAndSplitColourImg(app);
           
            imshow(app.ImgCellArray{1},'Parent',app.ImgAx);
            montage({app.ImgCellArray{2},app.ImgCellArray{3},app.ImgCellArray{4}}, 'Parent',app.ColorAX,'Size',[1 3]);
            
            app.TakepictureButton.Enable = "on";
        end

        % Button pushed function: SavepictureButton
        function SavepictureButtonPushed(app, event)
            app.DebugLabel.Text = string(app.SaveCurrentImg("all"));
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.forceSenseTimer);
            delete(app)
            
        end

        % Button pushed function: saveForceFigButton
        function saveForceFigButtonPushed(app, event)
            app.saveForceFigButton.Enable = "off";
            app.SaveForceFigure();
            app.saveForceFigButton.Enable = "on";
        end

        % Button pushed function: CreateNewTestButton
        function CreateNewTestButtonPushed2(app, event)
                        CreateNewTest(app); % in changing the layout of the application matlab didn't seem to like the orginal callback which is why this one is the second 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ForceSensorAxes = uiaxes(app.GridLayout);
            title(app.ForceSensorAxes, 'Title')
            xlabel(app.ForceSensorAxes, 'X')
            ylabel(app.ForceSensorAxes, 'Y')
            zlabel(app.ForceSensorAxes, 'Z')
            app.ForceSensorAxes.Tag = 'ForceAxis';
            app.ColorAX = uiaxes(app.GridLayout);
            title(app.ColorAX, 'Colour Channels')
            xlabel(app.ColorAX, 'Red | Green | Blue')
            app.ColorAX.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.ImgAx = uiaxes(app.GridLayout);
            title(app.ImgAx, 'Taken image')
            app.ArmPositionAxes = uiaxes(app.GridLayout);
            xlabel(app.ArmPositionAxes, 'X')
            ylabel(app.ArmPositionAxes, 'Y')
            zlabel(app.ArmPositionAxes, 'Z')
            app.ArmPositionAxes.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'};
            app.TestIDLabel = uilabel(app.GridLayout);
            app.TestIDLabel.Text = 'Test ID';
            app.CreateNewTestButton = uibutton(app.GridLayout, 'push');
            app.CreateNewTestButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNewTestButtonPushed2, true);
            app.CreateNewTestButton.Text = 'Create New Test';
            app.saveForceFigButton = uibutton(app.GridLayout, 'push');
            app.saveForceFigButton.ButtonPushedFcn = createCallbackFcn(app, @saveForceFigButtonPushed, true);
            app.saveForceFigButton.Text = 'Save Figure';
            app.DebugLabel = uilabel(app.GridLayout);
            app.DebugLabel.Text = 'Default';
            app.TakepictureButton = uibutton(app.GridLayout, 'push');
            app.TakepictureButton.ButtonPushedFcn = createCallbackFcn(app, @TakepictureButtonPushed, true);
            app.TakepictureButton.Text = 'Take picture';
            app.SavepictureButton = uibutton(app.GridLayout, 'push');
            app.SavepictureButton.ButtonPushedFcn = createCallbackFcn(app, @SavepictureButtonPushed, true);
            app.SavepictureButton.Text = 'Save picture';
            app.CameraModelLabel = uilabel(app.GridLayout);
            app.CameraModelLabel.Text = 'Camera Model';
            app.DebugLabeltitle = uilabel(app.GridLayout);
            app.DebugLabeltitle.Text = 'Debug';
            app.XInputLabel = uilabel(app.GridLayout);
            app.XInputLabel.Text = 'XInput';
            app.ArmXSlider = uislider(app.GridLayout);
            app.ArmXSlider.Limits = [0 90];
            app.YLabel = uilabel(app.GridLayout);
            app.YLabel.Text = 'YInput';
            app.ArmYSlider = uislider(app.GridLayout);
            app.ColourSelectDropDownLabel = uilabel(app.GridLayout);
            app.ColourSelectDropDownLabel.Text = 'Colour Select';
            app.ColourSelectDropDown = uidropdown(app.GridLayout);
            app.ArmpositionLabel = uilabel(app.GridLayout);
            app.ArmpositionLabel.Text = 'Arm position';
            app.ForcedisplayLabel = uilabel(app.GridLayout);
            app.ForcedisplayLabel.Text = 'Force display';
            app.PicturenameEditFieldLabel = uilabel(app.GridLayout);
            app.PicturenameEditFieldLabel.Text = 'Picture name';
            app.PicturenameEditField = uieditfield(app.GridLayout, 'text');
            app.PicturenameEditField.Value = 'Picture name';
            app.SwabButton = uibutton(app.GridLayout, 'push');
            app.SwabButton.Text = 'Swab';
            app.SetpositionButton = uibutton(app.GridLayout, 'push');
            app.SetpositionButton.Text = 'Set position';
            app.FigurenameEditFieldLabel = uilabel(app.GridLayout);
            app.FigurenameEditFieldLabel.Text = 'Figure name';
            app.FigurenameEditField = uieditfield(app.GridLayout, 'text');
            app.FigurenameEditField.Value = 'Figure name';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Swabbing_test_application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end